classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        saveMenu                    matlab.ui.container.Menu
        saveannotationsascsvMenu    matlab.ui.container.Menu
        loadeventtimesMenu          matlab.ui.container.Menu
        loadeventtracesMenu         matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        MultirecordingexperimentsetingsMenu  matlab.ui.container.Menu
        SetexperimentdateMenu       matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        TroughsTab                  matlab.ui.container.Tab
        SmoothingSpanKnobLabel      matlab.ui.control.Label
        SmoothingSpanKnob           matlab.ui.control.Knob
        MinTroughProminanceLabel    matlab.ui.control.Label
        MinTroughProminanceKnob     matlab.ui.control.Knob
        coefficientofvariationKnobLabel  matlab.ui.control.Label
        coefficientofvariationKnob  matlab.ui.control.Knob
        TroughAbruptnessTab         matlab.ui.container.Tab
        AbruptnessGauge_2Label      matlab.ui.control.Label
        AbruptnessGauge_2           matlab.ui.control.SemicircularGauge
        FiterrorrsquaredGaugeLabel  matlab.ui.control.Label
        FiterrorrsquaredGauge       matlab.ui.control.SemicircularGauge
        AbruptnessTab               matlab.ui.container.Tab
        HighPassFrequencyKnobLabel  matlab.ui.control.Label
        HighPassFrequencyKnob       matlab.ui.control.Knob
        WindowsizeLabel             matlab.ui.control.Label
        WindowsizeSlider            matlab.ui.control.Slider
        AbruptnessGaugeLabel        matlab.ui.control.Label
        AbruptnessGauge             matlab.ui.control.SemicircularGauge
        FitErrorRSquaredGaugeLabel  matlab.ui.control.Label
        FitErrorRSquaredGauge       matlab.ui.control.SemicircularGauge
        CoastlineTab                matlab.ui.container.Tab
        PowerTab                    matlab.ui.container.Tab
        SelectEventDropDownLabel    matlab.ui.control.Label
        SelectEventDropDown         matlab.ui.control.DropDown
        ReadyLampLabel              matlab.ui.control.Label
        ReadyLamp                   matlab.ui.control.Lamp
        ReanalyseallwithcurrentparametersButton  matlab.ui.control.Button
        ConditionEditFieldLabel     matlab.ui.control.Label
        ConditionEditField          matlab.ui.control.EditField
        ApplytoallButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        event_times % Struct: Contains the start and end times of events
        selected_event % Int: points to the currently selected event
        highlightline % Line: The line underneath the selected event
        parameters % Struct: Contains the parameters set by the user.
        conditions % cell array: conditions of each event
        num_events % Integer: number of events
    end
    
    methods (Access = private)
        
        function updateGuages(app)
            try
                app.AbruptnessGauge.Value = app.event_times.Abruptness{app.selected_event}.feats.Abruptness;
                app.FitErrorRSquaredGauge.Value = app.event_times.Abruptness{app.selected_event}.feats.fit_error;
                app.AbruptnessGauge_2.Value = app.event_times.Abruptness{app.selected_event}.feats.Abruptness_trough;
                app.FiterrorRSquaredGauge.Value = app.event_times.Abruptness{app.selected_event}.feats.fit_error_trough;
            catch
            end
        end
        
        function getOffsetandRecNum(app)
            prompt = {'Offset:', 'Recording number:'};
            title = 'Multi-recording experiment parameters';
            dims = [1 35];
            definput = {num2str(app.parameters.offset),num2str(app.parameters.rec_num )};
            x = inputdlg(prompt,title,dims,definput);
            app.parameters.offset = str2num(x{1});
            app.parameters.rec_num = str2num(x{2});
            
            
        end
        function getDateandExpNum(app)
            prompt = {'Date:', 'Experiment number (if multiple experiments on a single date):'};
            title = 'Experiment date and number';
            dims = [1 35];
            definput = {datestr(app.parameters.rec_date),num2str(app.parameters.slice_num) };
            x = inputdlg(prompt,title,dims,definput);
            app.parameters.rec_date = datetime(x{1});
            app.parameters.slice_num = str2num(x{2});
        end
        
        function loaddatafile(app,datafile)
            if endsWith(datafile,'.axgd')
                [data_vec, time_vec, meta] = importaxo(datafile);
            elseif endsWith(datafile,'.mat')
                file_struct = load(datafile);
                fnames = fieldnames(file_struct);
                for i = 1:length(fnames)
                    if isfield(file_struct.(fnames{i}),'values')
                        data_vec{i} = file_struct.(fnames{i}).values;
                        time_vec{i} = file_struct.(fnames{i}).interval:file_struct.(fnames{i}).interval:length(data_vec{i})*file_struct.(fnames{i}).interval;
                    end
                end
                time_vec = time_vec{app.event_times.trace_uni};
            end
            fi = dir(datafile);
            app.parameters.rec_date = fi.date;
            
            app.parameters.rec_length = time_vec(end);
            app.event_times = compileEventTraces(data_vec,time_vec,app.event_times);
            app.num_events = length(app.event_times.eventtraces);
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, timesfile, datafile)
            app.parameters.maxtroughwidth = 3; % default max trough width
            app.parameters.smooth = true;
            app.parameters.smoothwidth = 200;
            app.parameters.minpeakprominance = 50;
            app.parameters.threshold = 0;
            app.parameters.window = 1;
            app.parameters.hpfreq = 300;
            app.parameters.offset = 0;
            app.parameters.cv = 1;
            app.parameters.rec_length = 0;
            app.num_events = 0;
            app.parameters.rec_num = 1;
            app.parameters.slice_num = 1;
            app.parameters.rec_date = datetime(2000,1,1);            
            if nargin>1
                app.event_times= loadseizuretimes(timesfile);
                loaddatafile(app,datafile);
                app.selected_event = 1;
                plotevents(app.UIAxes2,app.event_times);
                app.highlightline = highlightevent(app.UIAxes2,[app.event_times.starttime(app.selected_event) ...
                    app.event_times.endtime(app.selected_event)], max(app.event_times.eventtraces{app.selected_event}));
                eventnames = cell(length(app.event_times.eventtraces),1);
                for i = 1:length(app.event_times.eventtraces)
                    eventnames{i} = ['event ' num2str(i)];
                    app.conditions{i} = 'N/A';
                    Abruptness.MUA_fit = [];
                    Abruptness.MUA_fit_time =[];
                    Abruptness.MUA_logk =[];
                    Abruptness.feats.Abruptness = [];
                    Abruptness.feats.fit_error = [];
                    Abruptness.feats.Abruptness_trough = [];
                    Abruptness.feats.fit_error_trough = [];
                    Abruptness.feats.maxMUA = [];
                    Abruptness.feats.meanMUA = [];
                    Abruptness.mua = [];
                    app.event_times.Abruptness{i} = Abruptness;
                end
                app.SelectEventDropDown.Items = eventnames;
                app.SelectEventDropDown.ItemsData = 1:length(app.event_times.eventtraces);
                plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
                app.event_times = calculateMetrics(app.event_times,'all',app.parameters);
                plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
                updateGuages(app);
            end
            
        end

        % Callback function
        function loadabfMenuSelected(app, event)
            
        end

        % Menu selected function: loadeventtimesMenu
        function loadeventtimesMenuSelected(app, event)
            [baseName, folder] = uigetfile('.csv');
            app.event_times= loadseizuretimes(fullfile(folder,baseName));
        end

        % Menu selected function: loadeventtracesMenu
        function loadeventtracesMenuSelected(app, event)
            
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            dataloaded = true;
            [baseName, folder]  = uigetfile({'*.axgd';'*.smr';'*.mat'},'Select data file.');
            filename = fullfile(folder,baseName);
            loaddatafile(app,filename);
            
            if isfield(app.event_times, 'trace') && dataloaded
                loaddatafile(app,filename);
                app.selected_event = 1;
                plotevents(app.UIAxes2,app.event_times);
                app.highlightline = highlightevent(app.UIAxes2,[app.event_times.starttime(app.selected_event) ...
                    app.event_times.endtime(app.selected_event)], max(app.event_times.eventtraces{app.selected_event}));
                eventnames = cell(length(app.event_times.eventtraces),1);
                for i = 1:length(app.event_times.eventtraces)
                    eventnames{i} = ['event ' num2str(i)];
                    app.conditions{i} = 'N/A';
                    Abruptness.MUA_fit = [];
                    Abruptness.MUA_fit_time =[];
                    Abruptness.MUA_logk =[];
                    Abruptness.feats.Abruptness = [];
                    Abruptness.feats.fit_error = [];
                    Abruptness.feats.Abruptness_trough = [];
                    Abruptness.feats.fit_error_trough = [];
                    Abruptness.feats.maxMUA = [];
                    Abruptness.feats.meanMUA = [];
                    Abruptness.mua = [];
                    app.event_times.Abruptness{i} = Abruptness;
                end
                app.SelectEventDropDown.Items = eventnames;
                app.SelectEventDropDown.ItemsData = 1:length(app.event_times.eventtraces);
                plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
                app.event_times = calculateMetrics(app.event_times,'all',app.parameters);
                plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
                updateGuages(app);
            else
                helpdlg('Event times must be loaded first.');
            end
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
        end

        % Value changed function: SelectEventDropDown
        function SelectEventDropDownValueChanged(app, event)
            app.selected_event = app.SelectEventDropDown.Value;
            app.highlightline = highlightevent(app.UIAxes2,[app.event_times.starttime(app.selected_event) ...
                app.event_times.endtime(app.selected_event)], max(app.event_times.eventtraces{app.selected_event}),app.highlightline);
            if strcmp(app.TabGroup.SelectedTab.Title, 'Troughs')
                plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
            end
            app.event_times = calculateMetrics(app.event_times,'all',app.parameters,app.selected_event);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateGuages(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            cla(app.UIAxes)
            app.UIAxes.reset()
            if strcmp(app.TabGroup.SelectedTab.Title, 'Troughs')
                plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
            end
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateGuages(app);
        end

        % Value changing function: SmoothingSpanKnob
        function SmoothingSpanKnobValueChanging(app, event)
            app.parameters.smoothwidth = event.Value;
            if app.parameters.smoothwidth == 0
                app.parameters.smooth = false;
            else
                app.parameters.smooth = true;
            end
            plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
        end

        % Value changed function: MinTroughProminanceKnob
        function MinTroughProminanceKnobValueChanged(app, event)
            
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            app.parameters.minpeakprominance = app.MinTroughProminanceKnob.Value;
            app.event_times = calculateMetrics(app.event_times,app.TabGroup.SelectedTab.Title,app.parameters,app.selected_event);
            plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
        end

        % Value changed function: SmoothingSpanKnob
        function SmoothingSpanKnobValueChanged(app, event)
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            app.event_times = calculateMetrics(app.event_times,app.TabGroup.SelectedTab.Title,app.parameters,app.selected_event);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
        end

        % Callback function
        function TroughThresholdKnobValueChanged(app, event)
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            app.parameters.threshold = app.TroughThresholdKnob.Value;
            app.event_times = calculateMetrics(app.event_times,app.TabGroup.SelectedTab.Title,app.parameters,app.selected_event);
            plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
        end

        % Value changed function: HighPassFrequencyKnob
        function HighPassFrequencyKnobValueChanged(app, event)
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            app.parameters.hpfreq = app.HighPassFrequencyKnob.Value;
            
            app.event_times = calculateMetrics(app.event_times,app.TabGroup.SelectedTab.Title,app.parameters,app.selected_event);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateGuages(app);
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
            
        end

        % Value changed function: WindowsizeSlider
        function WindowsizeSliderValueChanged(app, event)
            
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            app.parameters.window = app.WindowsizeSlider.Value;
            app.event_times = calculateMetrics(app.event_times,app.TabGroup.SelectedTab.Title,app.parameters,app.selected_event);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateGuages(app);
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
        end

        % Menu selected function: saveannotationsascsvMenu
        function saveannotationsascsvMenuSelected(app, event)
            filename = uiputfile({'*.csv'; '*.xls'},'Select spreadsheet file to save results into.');
            saveAnnotations(app.event_times,app.conditions,app.parameters,filename)
        end

        % Value changed function: ConditionEditField
        function ConditionEditFieldValueChanged(app, event)
            value = app.ConditionEditField.Value;
            app.conditions{app.selected_event} = value;
        end

        % Button pushed function: ApplytoallButton
        function ApplytoallButtonPushed(app, event)
            value = app.conditions{app.selected_event};
            for i_event = 1:app.num_events
                app.conditions{i_event} = value;
            end
        end

        % Button pushed function: 
        % ReanalyseallwithcurrentparametersButton
        function ReanalyseallwithcurrentparametersButtonPushed(app, event)
            app.event_times = calculateMetrics(app.event_times,'all',app.parameters);
        end

        % Menu selected function: MultirecordingexperimentsetingsMenu
        function MultirecordingexperimentsetingsMenuSelected(app, event)
            getOffsetandRecNum(app);
        end

        % Menu selected function: SetexperimentdateMenu
        function SetexperimentdateMenuSelected(app, event)
            getDateandExpNum(app);
        end

        % Value changed function: coefficientofvariationKnob
        function coefficientofvariationKnobValueChanged(app, event)
            value = app.coefficientofvariationKnob.Value;
            app.parameters.cv = value;
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'loading');
            app.event_times = calculateMetrics(app.event_times,app.TabGroup.SelectedTab.Title,app.parameters,app.selected_event);
            plotSingleEvent(app.UIAxes,app.event_times,app.selected_event,app.parameters);
            plotCurrentMetric(app.UIAxes,app.event_times,app.selected_event, app.TabGroup.SelectedTab,app.parameters);
            updateGuages(app);
            updateLampState(app.ReadyLamp,app.ReadyLampLabel,'Ready');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.saveMenu = uimenu(app.FileMenu);
            app.saveMenu.Text = 'save';
            app.saveannotationsascsvMenu = uimenu(app.saveMenu);
            app.saveannotationsascsvMenu.MenuSelectedFcn = createCallbackFcn(app, @saveannotationsascsvMenuSelected, true);
            app.saveannotationsascsvMenu.Text = 'save annotations as csv';
            app.loadeventtimesMenu = uimenu(app.FileMenu);
            app.loadeventtimesMenu.MenuSelectedFcn = createCallbackFcn(app, @loadeventtimesMenuSelected, true);
            app.loadeventtimesMenu.Text = 'load event times';
            app.loadeventtracesMenu = uimenu(app.FileMenu);
            app.loadeventtracesMenu.MenuSelectedFcn = createCallbackFcn(app, @loadeventtracesMenuSelected, true);
            app.loadeventtracesMenu.Text = 'load event traces';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.MultirecordingexperimentsetingsMenu = uimenu(app.EditMenu);
            app.MultirecordingexperimentsetingsMenu.MenuSelectedFcn = createCallbackFcn(app, @MultirecordingexperimentsetingsMenuSelected, true);
            app.MultirecordingexperimentsetingsMenu.Text = 'Multi-recording experiment setings';
            app.SetexperimentdateMenu = uimenu(app.EditMenu);
            app.SetexperimentdateMenu.MenuSelectedFcn = createCallbackFcn(app, @SetexperimentdateMenuSelected, true);
            app.SetexperimentdateMenu.Text = 'Set experiment date';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'LFP (mV)')
            app.UIAxes2 = uiaxes(app.Panel);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'LFP (mV)')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TroughsTab = uitab(app.TabGroup);
            app.TroughsTab.Title = 'Troughs';
            app.SmoothingSpanKnobLabel = uilabel(app.TroughsTab);
            app.SmoothingSpanKnobLabel.Text = 'Smoothing Span';
            app.SmoothingSpanKnob = uiknob(app.TroughsTab, 'continuous');
            app.SmoothingSpanKnob.Limits = [0 500];
            app.SmoothingSpanKnob.ValueChangedFcn = createCallbackFcn(app, @SmoothingSpanKnobValueChanged, true);
            app.SmoothingSpanKnob.ValueChangingFcn = createCallbackFcn(app, @SmoothingSpanKnobValueChanging, true);
            app.SmoothingSpanKnob.Value = 200;
            app.MinTroughProminanceLabel = uilabel(app.TroughsTab);
            app.MinTroughProminanceLabel.Text = 'Min Trough Prominance %';
            app.MinTroughProminanceKnob = uiknob(app.TroughsTab, 'continuous');
            app.MinTroughProminanceKnob.Limits = [1 99];
            app.MinTroughProminanceKnob.MajorTicks = [1 15 30 45 60 75 90];
            app.MinTroughProminanceKnob.ValueChangedFcn = createCallbackFcn(app, @MinTroughProminanceKnobValueChanged, true);
            app.MinTroughProminanceKnob.Value = 50;
            app.coefficientofvariationKnobLabel = uilabel(app.TroughsTab);
            app.coefficientofvariationKnobLabel.Text = 'coefficient of variation';
            app.coefficientofvariationKnob = uiknob(app.TroughsTab, 'continuous');
            app.coefficientofvariationKnob.Limits = [0.2 2];
            app.coefficientofvariationKnob.ValueChangedFcn = createCallbackFcn(app, @coefficientofvariationKnobValueChanged, true);
            app.coefficientofvariationKnob.Value = 1;
            app.TroughAbruptnessTab = uitab(app.TabGroup);
            app.TroughAbruptnessTab.Title = 'TroughAbruptness';
            app.AbruptnessGauge_2Label = uilabel(app.TroughAbruptnessTab);
            app.AbruptnessGauge_2Label.Text = 'Abruptness';
            app.AbruptnessGauge_2 = uigauge(app.TroughAbruptnessTab, 'semicircular');
            app.AbruptnessGauge_2.Limits = [0 10];
            app.FiterrorrsquaredGaugeLabel = uilabel(app.TroughAbruptnessTab);
            app.FiterrorrsquaredGaugeLabel.Text = 'Fit error (r squared)';
            app.FiterrorrsquaredGauge = uigauge(app.TroughAbruptnessTab, 'semicircular');
            app.FiterrorrsquaredGauge.Limits = [0.5 1];
            app.FiterrorrsquaredGauge.ScaleDirection = 'counterclockwise';
            app.FiterrorrsquaredGauge.Value = 1;
            app.AbruptnessTab = uitab(app.TabGroup);
            app.AbruptnessTab.Title = 'Abruptness';
            app.HighPassFrequencyKnobLabel = uilabel(app.AbruptnessTab);
            app.HighPassFrequencyKnobLabel.Text = 'High Pass Frequency';
            app.HighPassFrequencyKnob = uiknob(app.AbruptnessTab, 'continuous');
            app.HighPassFrequencyKnob.Limits = [100 500];
            app.HighPassFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @HighPassFrequencyKnobValueChanged, true);
            app.HighPassFrequencyKnob.Value = 100;
            app.WindowsizeLabel = uilabel(app.AbruptnessTab);
            app.WindowsizeLabel.Text = {'Window'; 'size'};
            app.WindowsizeSlider = uislider(app.AbruptnessTab);
            app.WindowsizeSlider.Limits = [0.5 3];
            app.WindowsizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSliderValueChanged, true);
            app.WindowsizeSlider.Value = 1.5;
            app.AbruptnessGaugeLabel = uilabel(app.AbruptnessTab);
            app.AbruptnessGaugeLabel.Text = 'Abruptness';
            app.AbruptnessGauge = uigauge(app.AbruptnessTab, 'semicircular');
            app.AbruptnessGauge.Limits = [0 10];
            app.AbruptnessGauge.Value = 2;
            app.FitErrorRSquaredGaugeLabel = uilabel(app.AbruptnessTab);
            app.FitErrorRSquaredGaugeLabel.Text = 'Fit Error (R Squared)';
            app.FitErrorRSquaredGauge = uigauge(app.AbruptnessTab, 'semicircular');
            app.FitErrorRSquaredGauge.Limits = [0.5 1];
            app.FitErrorRSquaredGauge.ScaleDirection = 'counterclockwise';
            app.FitErrorRSquaredGauge.Value = 1;
            app.CoastlineTab = uitab(app.TabGroup);
            app.CoastlineTab.Title = 'Coastline';
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.SelectEventDropDownLabel = uilabel(app.UIFigure);
            app.SelectEventDropDownLabel.Text = 'Select Event';
            app.SelectEventDropDown = uidropdown(app.UIFigure);
            app.SelectEventDropDown.Items = {};
            app.SelectEventDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventDropDownValueChanged, true);
            app.SelectEventDropDown.Value = {};
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReanalyseallwithcurrentparametersButton = uibutton(app.UIFigure, 'push');
            app.ReanalyseallwithcurrentparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ReanalyseallwithcurrentparametersButtonPushed, true);
            app.ReanalyseallwithcurrentparametersButton.Text = {'Re-analyse all with'; ' current parameters'};
            app.ConditionEditFieldLabel = uilabel(app.UIFigure);
            app.ConditionEditFieldLabel.Text = 'Condition';
            app.ConditionEditField = uieditfield(app.UIFigure, 'text');
            app.ConditionEditField.ValueChangedFcn = createCallbackFcn(app, @ConditionEditFieldValueChanged, true);
            app.ApplytoallButton = uibutton(app.UIFigure, 'push');
            app.ApplytoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallButtonPushed, true);
            app.ApplytoallButton.Text = 'Apply to all';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end